classdef InerterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        FunctionGeneratorSwitch         matlab.ui.control.Switch
        FunctionGeneratorSwitchLabel    matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        InerterSpecsLabel               matlab.ui.control.Label
        CalibrateLoadCellButton         matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        InerterMassEditField            matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        InnerRadiiEditField             matlab.ui.control.NumericEditField
        InnerRadiimEditFieldLabel       matlab.ui.control.Label
        OuterRadiiEditField             matlab.ui.control.NumericEditField
        OuterRadiimEditFieldLabel       matlab.ui.control.Label
        numGearsEditField               matlab.ui.control.NumericEditField
        ofGearsEditFieldLabel           matlab.ui.control.Label
        CalculatedInertanceEditField_2  matlab.ui.control.NumericEditField
        CalculatedInertancekgEditFieldLabel_2  matlab.ui.control.Label
        TimesSlider                     matlab.ui.control.Slider
        TimesSliderLabel                matlab.ui.control.Label
        StartDataCollectionButton       matlab.ui.control.StateButton
        ExperimentalInertanceEditField  matlab.ui.control.NumericEditField
        ExperimentalInertanceLabel      matlab.ui.control.Label
        PerformInertanceCalculationButton  matlab.ui.control.Button
        FourierTransformPlot            matlab.ui.control.UIAxes
        ForcePlot                       matlab.ui.control.UIAxes
        AccPlot                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        % Data Collection
        forceDataArray % Array storing force data from load cell
        accDataArray % Array storing accelerometer data
        accTimeArray % Array storing time data from accelerometer/daq
        forceTimeArray % Array storing time data from arduino load cell

        % Calculated Data
        F_exp % Experimentally determined F value
        a_exp % Experimentally determined a value
        b_exp % Experimental b value
        b_theo % Theoretical b value
        forceFD % Force fft
        accFD % Acceleration fft

        % Arduino
        a % Arduino connection
        Gain % Gain for load cell
        Interrupt % Interupt property
        LoadCell % Load cell property
        Tare % Calibration variable
        KnownWeight % Known calibration weight
        Scale % Calibration sale factor

        % Daq
        d % Daq connection
        ch % Daq channel

        % Function Generator
        v
        FreqVal
        AmpVal

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PerformInertanceCalculationButton
        function CalculateInertance(app, event)
            % Pulling current data displayed from the graphs

            % Only generates experimental results if data is present
            if ~isempty(app.forceDataArray) && ~isempty(app.accDataArray)

                % Frequency Domain Analysis
                app.forceFD = 2 * (abs(fft(app.forceDataArray))./length(app.forceFD)); % Force data in frequency domain
                app.accFD = 2 * (abs(fft(app.accDataArray))./length(app.accFD));     % Acceleration data in frequency domain
                app.F_exp = max(app.forceFD);              % Experimentally determined F value
                app.a_exp = max(app.accFD);                % Experimentally determined a value

                % Calculating experimental inertance value
                app.b_exp = real(app.F_exp/app.a_exp);
            else
                app.b_exp = 0;
            end

            if app.InnerRadiiEditField.Value ~= 0

                % Calculating theoretical inertance value
                R = app.OuterRadiiEditField.Value;        % Outer gear radius (m)
                r = app.InnerRadiiEditField.Value;        % Inner gear radius (m)
                m = app.InerterMassEditField.Value;       % Mass of inerter (kg)
                n = app.numGearsEditField.Value;          % Number of gears in train

                %%%%%%%%
                %%%%%%%%
                % UPDATE THIS EQUATION FOR THE SPECIFIED INERTER
                %%%%%%%%

                app.b_theo = (R^2/r^2)^n * m; % GEAR INERTER

                % app.b_theo = (mRack + J / r ^ 2) ; % DRAGESTER (CHAD) INERTER

                %%%%%%%%


            else

                app.b_theo = 0;

            end


            % Displaying inertance results to GUI
            if isnan(app.b_exp)
                fig = uifigure;
                msg = "Inertance calculation error. Please collect new data and try again.";
                title = "B-value Calculation Error";

                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            else
                app.ExperimentalInertanceEditField.Value = app.b_exp;
                app.CalculatedInertanceEditField_2.Value = app.b_theo;
                hold(app.FourierTransformPlot, 'on')
                yyaxis(app.FourierTransformPlot, 'left')
                plot(app.FourierTransformPlot, abs(app.forceFD) , 'r-');
                yyaxis(app.FourierTransformPlot, 'right')
                plot(app.FourierTransformPlot, abs(app.accFD) , 'b-');
                legend(app.FourierTransformPlot, 'Force', 'Acceleration')
                hold(app.FourierTransformPlot, 'off')
            end
        end

        % Value changed function: StartDataCollectionButton
        function StartDataCollectionButtonValueChanged(app, event)

            % Setup daq
            if isempty(app.d)
                app.d = daq("ni"); % Create daq object
                app.ch = addinput(app.d, "cDAQ1Mod1", "ai0", "Accelerometer"); % Add input for accelerometer
                app.d.Rate = 2048; % Data collection rate of accelerometer
                app.ch.Sensitivity = 0.00999; % See accelerometer paper in box
            end

            % Data Arrays
            app.forceDataArray = [];
            app.forceTimeArray = [];
            app.accDataArray = [];
            app.accTimeArray = [];

            % Check if has been calibrated
            if isempty(app.Scale)
                fig = uifigure;
                msg = "Must calibrate before collecting data.";
                title = "Data Collection Error";

                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            end


            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            % lbl = uilabel(fig);
            % lbl.WordWrap = "on";
            % lbl.Text = "Collecting Force and Acceleration Data.";
            % lbl.Position = [100 100 100 60];
            uiprogressdlg(fig, 'Title', "Data Collection", 'Message', "Collecting Force and Acceleration Data.")


            % Start data collection
            start(app.d, "duration", app.TimesSlider.Value);

            tStart = tic; % Start timer

            while toc(tStart) < app.TimesSlider.Value

               % Move division and math outside of loop

                app.forceDataArray = [app.forceDataArray (app.LoadCell.read_HX711 - app.Tare) / app.Scale]; % Force data collection
                app.forceTimeArray = [app.forceTimeArray toc(tStart)]; % Time from MATLAB

            end

            while app.d.Running
                [data, timestamps, ~] = read(app.d, "OutputFormat", "Matrix"); % Collect time from daq

                app.accTimeArray = [app.accTimeArray; timestamps]; % Acceleration data collection

                app.accDataArray = [app.accDataArray; data]; % Time from daq
            end

            % Plots for data
            plot(app.ForcePlot, app.forceTimeArray ,app.forceDataArray, 'k-');
            plot(app.AccPlot, app.accTimeArray, app.accDataArray, 'k-');

            delete(fig)

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)

            if isempty(app.forceDataArray)
                fig = uifigure;
                msg = "No data collected.";
                title = "Save Error";

                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            end

            if isfile(app.FilenameEditField.Value)

                fig = uifigure;
                msg = "Saving these changes will overwrite previous changes.";
                title = "Confirm Save";

                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Overwrite","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);

                if strcmp("Cancel", selection)
                    delete(fig)
                    return;
                end
            end

            if(size(app.forceDataArray, 2) <  size(app.accDataArray,1))

                T_Data = zeros(size(app.accDataArray,1),8);

            else

                T_Data = zeros(size(app.forceDataArray,1),8);

            end

            T_Data(1:size(app.forceDataArray, 2), 1) = app.forceDataArray';
            T_Data(1:size(app.forceDataArray, 2), 2) = app.forceTimeArray';
            T_Data(1:size(app.accDataArray, 1), 3) = app.accDataArray;
            T_Data(1:size(app.accDataArray, 1), 4) = app.accTimeArray;

            if isempty(app.b_theo)
                app.F_exp = 0;
                app.a_exp = 0;
                app.b_theo = 0;
                app.b_exp = 0;
            end

            T_Data(1, 5) = app.F_exp';
            T_Data(1:size(app.forceFD, 2), 6) = abs(app.forceFD)';
            T_Data(1, 7) = app.a_exp';
            T_Data(1:size(app.accFD, 1), 8) = abs(app.accFD);
            T_Data(1, 9) = app.b_theo';
            T_Data(1, 10) = app.b_exp';

            T = array2table(T_Data,'VariableNames', ...
                {'Force', 'Force Time', ...
                'Acceleration', 'Acceleration Time',...
                'Force FFT Max', 'Force FFT', 'Acceleration FFT Max',...
                'Acceleration FFT', 'Theoretical B value','Experimental B value'});

            writetable(T, app.FilenameEditField.Value)

        end

        % Button pushed function: CalibrateLoadCellButton
        function CalibrateLoadCell(app, event)

            try
                app.a = arduino('COM7', 'Uno', 'Libraries', 'advancedHX711/advanced_HX711');
                app.Gain = 128;
                app.Interrupt = false;

                app.LoadCell = addon(app.a,'advancedHX711/advanced_HX711',...
                    'Pins',{'D3','D2'},'Gain',128,'Interrupt',false);

            catch
                fig = uifigure;
                uialert(fig, "Arduino not connected.", 'Invalid Connection')
                delete(fig);
                return
            end

            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            msg = "Perform new calibration or use previous calibration data?";
            title = "Calibration";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["New Calibration","Load Previous Data"], ...
                "DefaultOption",1,"CancelOption",2);

            if strcmp(selection, "Load Previous Data")
                CalVals = readtable("Calibration.csv");
                app.Tare = CalVals{1,1};
                app.Scale = CalVals{1,2};
                delete(fig);
                return
            end

            delete(fig);

            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            msg = "Set MT160 to upright position, remove any attachments from load cell.";
            title = "Tare";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["Begin Tare","Cancel"], ...
                "DefaultOption",1,"CancelOption",2);

            if strcmp(selection, "Cancel")
                return
            end

            delete(fig);

            % Tare
            z = 100;
            x = 1:1:z;
            for j=1:1:z
                x(j) = app.LoadCell.read_HX711;
                pause(1/1000);
            end
            app.Tare = sum(x)./z;

            prompt = {'Enter and attach calibration weight [kg]:'};
            dlgtitle = 'Input';
            fieldsize = [1 45];
            definput = {'0.08007'};
            app.KnownWeight = str2double(inputdlg(prompt,dlgtitle,fieldsize,definput));

            x = 1:1:z;
            for j=1:1:z
                x(j) = app.LoadCell.read_HX711;
                pause(1/1000);
            end

            app.Scale = (sum(x)./z - app.Tare)/app.KnownWeight;

            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            msg = "Calibration complete.";
            title = "Calibration";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["Ok"], ...
                "DefaultOption",1);

            CalibrationValues = [app.Tare, app.Scale];
            writematrix(CalibrationValues, 'Calibration.csv')

            delete(fig);

        end

        % Value changed function: FunctionGeneratorSwitch
        function FunctionGeneratorSwitchValueChanged(app, event)
            if isempty(app.v)
                % visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::0::INSTR';
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            if strcmp(app.FunctionGeneratorSwitch.Value, 'On')

                fopen(app.v);

                s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

                t = timer;
                t.StartDelay(1);
                t.TimerFcn = @(~,~) fprintf(app.v, s);

                fprintf(app.v,'OUTP ON');

                fclose(app.v);

            elseif strcmp(app.FunctionGeneratorSwitch.Value, 'Off')
                fopen(app.v);
                fprintf(app.v,'OUTP OFF');
                fclose(app.v);
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)


            if isempty(app.v)
                % visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::0::INSTR';
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            fopen(app.v);
            s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

            fprintf(app.v, s);
            fclose(app.v);

        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            if isempty(app.v)
                % visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::0::INSTR';
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            fopen(app.v);
            s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

            fprintf(app.v, s);
            fclose(app.v);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccPlot = uiaxes(app.UIFigure);
            title(app.AccPlot, 'Acceleration Data')
            xlabel(app.AccPlot, 'Time (s)')
            ylabel(app.AccPlot, 'Acceleration (m/s^2)')
            zlabel(app.AccPlot, 'Z')
            app.ForcePlot = uiaxes(app.UIFigure);
            title(app.ForcePlot, 'Force Data')
            xlabel(app.ForcePlot, 'Time (s)')
            ylabel(app.ForcePlot, 'Force (N)')
            zlabel(app.ForcePlot, 'Z')
            app.FourierTransformPlot = uiaxes(app.UIFigure);
            title(app.FourierTransformPlot, 'Fourier Transform')
            xlabel(app.FourierTransformPlot, 'Frequency (Hz)')
            ylabel(app.FourierTransformPlot, 'Magnitude')
            app.PerformInertanceCalculationButton = uibutton(app.UIFigure, 'push');
            app.PerformInertanceCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInertance, true);
            app.PerformInertanceCalculationButton.Text = {'Perform'; 'Inertance'; 'Calculation'};
            app.ExperimentalInertanceLabel = uilabel(app.UIFigure);
            app.ExperimentalInertanceLabel.Text = {'Experimental'; 'Inertance (kg)'};
            app.ExperimentalInertanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'state');
            app.StartDataCollectionButton.ValueChangedFcn = createCallbackFcn(app, @StartDataCollectionButtonValueChanged, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [1 10];
            app.TimesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.TimesSlider.Value = 1;
            app.CalculatedInertancekgEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CalculatedInertancekgEditFieldLabel_2.Text = {'Calculated'; 'Inertance (kg)'};
            app.CalculatedInertanceEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CalculatedInertanceEditField_2.Editable = 'off';
            app.ofGearsEditFieldLabel = uilabel(app.UIFigure);
            app.ofGearsEditFieldLabel.Text = '# of Gears';
            app.numGearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiimEditFieldLabel.Text = 'Outer Radii (m)';
            app.OuterRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiimEditFieldLabel.Text = 'Inner Radii (m)';
            app.InnerRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.InerterMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.CalibrateLoadCellButton = uibutton(app.UIFigure, 'push');
            app.CalibrateLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateLoadCell, true);
            app.CalibrateLoadCellButton.Text = 'Calibrate Load Cell';
            app.InerterSpecsLabel = uilabel(app.UIFigure);
            app.InerterSpecsLabel.Text = 'Inerter Specs';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FunctionGeneratorSwitchLabel = uilabel(app.UIFigure);
            app.FunctionGeneratorSwitchLabel.Text = 'Function Generator';
            app.FunctionGeneratorSwitch = uiswitch(app.UIFigure, 'slider');
            app.FunctionGeneratorSwitch.ValueChangedFcn = createCallbackFcn(app, @FunctionGeneratorSwitchValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InerterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end